classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Switch             matlab.ui.control.Switch
        OctaveSlider       matlab.ui.control.Slider
        OctaveSliderLabel  matlab.ui.control.Label
        graph              matlab.ui.control.UIAxes
    end

    
 properties (Access = private)
       dataX = [0,1,3,1,0,-1,-3,-1,0,1,3,1,0] %dummy graph
       plot1, value1
       testF = 79 % dumy Freq
      isButtonEnable = false;
      octave = 0
    end

    
    methods (Access = private)
        function start(app)
                app.run();
        end
        function run(app) 
            u = udpport("Byte", "IPV4", "LocalPort", 8080, "EnablePortSharing", true);
            fs = 24000;
            N = 240;
            t = (0:N-1);
            frequencies = t * (fs / N);
            o = app.octave;
            WindowLen = N*3;
            AnalysisLen = N;
            SynthesisLen = round(AnalysisLen*2^(o/12));
            win = sqrt(hanning(WindowLen,'periodic'));
            ats = audioTimeScaler(AnalysisLen/SynthesisLen,'Window',win,'OverlapLength',WindowLen-AnalysisLen);
            hamming_window = hann(N)';
            %player = audioDeviceWriter('SampleRate',fs, 'SupportVariableSizeInput',true, 'BufferSize',1024, 'BitDepth', '24-bit integer');
            i = 0;
            data = [];
            shiftedData = [];
            while app.value1
                if o ~= app.octave
                    o = app.octave;
                    SynthesisLen = round(AnalysisLen*2^(o/12));
                    ats = audioTimeScaler(AnalysisLen/SynthesisLen,'Window',win,'OverlapLength',WindowLen-AnalysisLen);
                end
                x = read(u,N,"int32");
                x = bitshift(x, -8, "int32");
                x = x / 8388608.0 * 25;
                %data = horzcat(data, x);
                windowed_signal = x .* hamming_window;
                fft_result = fft(windowed_signal);
                magnitude_spectrum = abs(fft_result);
                xtrans = x';
                data = horzcat(data, x);
                y = ats(xtrans);
                z = resample(y, AnalysisLen, SynthesisLen);
                shiftedData = horzcat(shiftedData, z');
                if (i == 100)
                    drawUI(app, frequencies, magnitude_spectrum);
                    i = 0;
                end
                i = i + 1;
            end
            audiowrite("original.wav", data, fs);
            audiowrite("shifted.wav", shiftedData, fs);
        end
        function drawUI(app, frequencies, magnitude_spectrum)
            plot(app.graph, frequencies, magnitude_spectrum,"Color","b")
            drawnow;
        end
        function clearPlot(app)
            delete(app.plot1);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %app.poolObj = gcp('nocreate');
            %if isempty(app.poolObj)
                %app.poolObj = parpool; % Create a parallel pool
            %end
         
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.value1 = app.Switch.Value;
            if app.value1 == true
                start(app);
            else
                clearPlot(app);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: OctaveSlider
        function OctaveSliderValueChanged(app, event)
            app.octave = app.OctaveSlider.Value;
        end

        % Value changing function: OctaveSlider
        function OctaveSliderValueChanging(app, event)
            app.octave = event.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.graph = uiaxes(app.UIFigure);
            title(app.graph, 'Pitch Shifter')
            xlabel(app.graph, 'Frequency')
            ylabel(app.graph, 'Amplitude')
            zlabel(app.graph, 'Z')
            app.OctaveSliderLabel = uilabel(app.UIFigure);
            app.OctaveSliderLabel.Text = 'Octave';
            app.OctaveSlider = uislider(app.UIFigure);
            app.OctaveSlider.Limits = [-9 9];
            app.OctaveSlider.ValueChangedFcn = createCallbackFcn(app, @OctaveSliderValueChanged, true);
            app.OctaveSlider.ValueChangingFcn = createCallbackFcn(app, @OctaveSliderValueChanging, true);
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ItemsData = [false true];
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = false;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end